classdef viewMRI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        horizontalLabel  matlab.ui.control.Label
        coronalLabel     matlab.ui.control.Label
        sagittalLabel    matlab.ui.control.Label
        horSlider        matlab.ui.control.Slider
        corSlider        matlab.ui.control.Slider
        sagSlider        matlab.ui.control.Slider
        LoadFileButton   matlab.ui.control.Button
        sagPlot          matlab.ui.control.UIAxes
        horPlot          matlab.ui.control.UIAxes
        corPlot          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description

        nSagSlice
        sagSlice

        nCorSlice
        corSlice

        nHorSlice
        horSlice
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % niiFile = 'F:\Brandon\sub-M09_T1w.nii';
            niiFile = '/Users/brandonnanfito/Documents/sub-M09_T1w.nii';
            app.Data = niftiread(niiFile);
            app.sagSlider.Value = 1;
            app.sagSlider.Limits = [1 100];
            app.corSlider.Value = 1;
            app.corSlider.Limits = [1 100];
            app.horSlider.Value = 1;
            app.horSlider.Limits = [1 100];

            %sagittal
            app.nSagSlice = size(app.Data,3);
            app.sagSlice = (app.sagSlider.Value/max(app.sagSlider.Limits))*app.nSagSlice;
            imagesc(app.sagPlot, flipud(app.Data(:,:,round(app.sagSlice))') )
            colormap(app.sagPlot,'gray')

            %coronal
            app.nCorSlice = size(app.Data,1);
            app.corSlice = (app.corSlider.Value/max(app.corSlider.Limits))*app.nCorSlice;
            imagesc(app.corPlot, flipud(squeeze(app.Data(round(app.corSlice),:,:))) )
            colormap(app.corPlot,'gray')

            %horizontal
            app.nHorSlice = size(app.Data,2);
            app.horSlice = (app.horSlider.Value/max(app.horSlider.Limits))*app.nHorSlice;
            imagesc(app.horPlot, flipud(squeeze(app.Data(:,round(app.horSlice),:))) )
            colormap(app.horPlot,'gray')

            
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            %sagittal
            app.sagSlice = (app.sagSlider.Value/max(app.sagSlider.Limits))*app.nSagSlice;
            imagesc(app.sagPlot, flipud(app.Data(:,:,round(app.sagSlice))') )
            xline(app.corPlot,app.sagSlice,'r')
            xline(app.horPlot,app.sagSlice,'r')
            colormap(app.sagPlot,'gray')
        end

        % Value changing function: sagSlider
        function sagSliderValueChanging(app, event)
            %sagittal
            app.sagSlice = (event.Value/max(app.sagSlider.Limits))*app.nSagSlice;
            imagesc(app.sagPlot, flipud(app.Data(:,:,round(app.sagSlice))') )
            colormap(app.sagPlot,'gray')
        end

        % Value changed function: corSlider
        function corSliderValueChanged(app, event)
            %coronal
            app.corSlice = (app.corSlider.Value/max(app.corSlider.Limits))*app.nCorSlice;
            imagesc(app.corPlot, flipud(squeeze(app.Data(round(app.corSlice),:,:))) )
            xline(app.sagPlot,app.corSlice,'g')
            yline(app.horPlot,app.corSlice,'g')
            colormap(app.corPlot,'gray')
        end

        % Value changing function: corSlider
        function corSliderValueChanging(app, event)
            %coronal
            app.corSlice = (event.Value/max(app.corSlider.Limits))*app.nCorSlice;
            imagesc(app.corPlot, flipud(squeeze(app.Data(round(app.corSlice),:,:))) )
            colormap(app.corPlot,'gray')
        end

        % Value changed function: horSlider
        function horSliderValueChanged(app, event)
            %horizontal
            app.horSlice = (app.horSlider.Value/max(app.horSlider.Limits))*app.nHorSlice;
            imagesc(app.horPlot, squeeze(app.Data(:,round(app.horSlice),:)) )
            yline(app.sagPlot,app.horSlice,'b')
            yline(app.corPlot,app.horSlice,'b')
            colormap(app.horPlot,'gray')
        end

        % Value changing function: horSlider
        function horSliderValueChanging(app, event)
            %horizontal
            app.horSlice = (event.Value/max(app.horSlider.Limits))*app.nHorSlice;
            imagesc(app.horPlot, squeeze(app.Data(:,round(app.horSlice),:)) )
            colormap(app.horPlot,'gray')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.corPlot = uiaxes(app.UIFigure);
            title(app.corPlot, 'Title')
            xlabel(app.corPlot, 'X')
            ylabel(app.corPlot, 'Y')
            zlabel(app.corPlot, 'Z')
            app.horPlot = uiaxes(app.UIFigure);
            title(app.horPlot, 'Title')
            xlabel(app.horPlot, 'X')
            ylabel(app.horPlot, 'Y')
            zlabel(app.horPlot, 'Z')
            app.sagPlot = uiaxes(app.UIFigure);
            title(app.sagPlot, 'Title')
            xlabel(app.sagPlot, 'X')
            ylabel(app.sagPlot, 'Y')
            zlabel(app.sagPlot, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = {'Load '; 'File'};
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.Limits = [1 100];
            app.sagSlider.MajorTicks = [1 50 100];
            app.sagSlider.MajorTickLabels = {'lateral', 'medial', 'lateral'};
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.ValueChangingFcn = createCallbackFcn(app, @sagSliderValueChanging, true);
            app.sagSlider.Value = 1;
            app.corSlider = uislider(app.UIFigure);
            app.corSlider.Limits = [1 100];
            app.corSlider.MajorTicks = [1 100];
            app.corSlider.MajorTickLabels = {'anterior', 'posterior'};
            app.corSlider.ValueChangedFcn = createCallbackFcn(app, @corSliderValueChanged, true);
            app.corSlider.ValueChangingFcn = createCallbackFcn(app, @corSliderValueChanging, true);
            app.corSlider.Value = 1;
            app.horSlider = uislider(app.UIFigure);
            app.horSlider.Limits = [1 100];
            app.horSlider.MajorTicks = [1 100];
            app.horSlider.MajorTickLabels = {'ventral', 'dorsal'};
            app.horSlider.ValueChangedFcn = createCallbackFcn(app, @horSliderValueChanged, true);
            app.horSlider.ValueChangingFcn = createCallbackFcn(app, @horSliderValueChanging, true);
            app.horSlider.Value = 1;
            app.sagittalLabel = uilabel(app.UIFigure);
            app.sagittalLabel.Text = 'sagittal';
            app.coronalLabel = uilabel(app.UIFigure);
            app.coronalLabel.Text = 'coronal';
            app.horizontalLabel = uilabel(app.UIFigure);
            app.horizontalLabel.Text = 'horizontal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewMRI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end